classdef flag_diag_App_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1            matlab.ui.Figure
        text2              matlab.ui.control.Label
        new_flag           matlab.ui.control.EditField
        selection          matlab.ui.control.DropDown
        parameter          matlab.ui.control.DropDown
        text3              matlab.ui.control.Label
        text4              matlab.ui.control.Label
        profile            matlab.ui.control.EditField
        previous           matlab.ui.control.Button
        NEXT               matlab.ui.control.Button
        text6              matlab.ui.control.Label
        validate           matlab.ui.control.Button
        cancel             matlab.ui.control.Button
        text7              matlab.ui.control.Label
        text               matlab.ui.control.Label
        vertical_sampling  matlab.ui.control.DropDown
        pushbutton6        matlab.ui.control.Button
        ExitButton         matlab.ui.control.Button
        Addsigma0CheckBox  matlab.ui.control.CheckBox
        AddtempCheckBox    matlab.ui.control.CheckBox
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function flag_diag_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to flag_diag (see VARARGIN)
            n=length(varargin);
            
            if n/2~=floor(n/2)
                error('check the imput arguments')
            end
            
            f=varargin(1:2:end);
            c=varargin(2:2:end);
            s = cell2struct(c,f,2);
            
            if isfield(s,'Config')==1
                handles.floatname=s.Config.floatname;
                handles.dacname=s.Config.dacname;
                handles.DIR_FTP=s.Config.DIR_FTP;
                handles.FILE_TOPO=s.Config.FILE_TOPO;
                handles.VPN=s.Config.VPN;
                handles.FLAG=s.Config.FLAG;
                handles.DIR_PLOT=s.Config.DIR_PLOT;
                handles.NB_PROF=s.Config.NB_PROF;
                handles.KEEPZOOM=s.Config.KEEPZOOM;
            else
                disp('Warning:********* enter CONFIG as un imput argument: flag_diag(''Config'',CONFIG)')
                handles.floatname='';
                handles.dacname='';
                handles.DIR_FTP='';
                handles.VPN='';
                handles.FLAG='';
                handles.NB_PROF=1;
                handles.KEEPZOOM=1;
                
            end
            %keyboard
            %handles.figure1=figure();
            %uitoolbar(app.figure1)
            %set(hObject,'toolbar','Figure');
            % data for the plot
            %set(handles.figure1,'Color',[1 1 1]);
            handles.figure2=figure('Position', [620 105 1000 655],'Name',[handles.floatname]);
            addToolbarExplorationButtons(handles.figure2)
            [handles.file_list]=select_float_files_on_ftp(handles.floatname,handles.dacname,handles.DIR_FTP,'C');
            [handles.file_listB]=select_float_files_on_ftp(handles.floatname,handles.dacname,handles.DIR_FTP,'B');
            
            % Choose default command line output for flag_diag
            handles.output = hObject;
            set(handles.profile,'String','');
            
            Topo=read_netcdf_allthefile(handles.FILE_TOPO);
            %app.figure1.AutoResizeChildren = 'off';
            handles.topo=Topo;
            
            %handles.ax1=subplot(4,18,[1:5,19:23]); % bathy
            handles.p0 =uipanel ('Position',[.01 .01 .98 .98],'BorderWidth',0,'Title',handles.floatname,'FontSize',11,'FontWeight','bold','TitlePOsition',"centertop")
            p1 =uipanel('Position',[.01 .56 .435 .4]);
            t1 =tiledlayout(p1,1,1);
            handles.ax1=axes(t1); % bathy
            hold on
            box on
            p2 =uipanel('Position',[.45 .01 .54 .95]);
            t2 =tiledlayout(p2,1,1);
            handles.ax2=axes(t2);  % plot parameter
            handles.ax2b=axes(t2);  %second axe
            handles.ax2b.Visible='off';
            %handles.ax2=subplot(1,12,[7:12]);  % plot parameter
            handles.ax2.TitleHorizontalAlignment='right';
            box on
            %handles.ax3=subplot(4,18,[37:38,55:56]); % petit plot P,T
            %handles.ax3=subplot(4,18,[37:39,55:57]); % petit plot P,T
            p3 =uipanel('Position',[.01 .01 .215 .54]);
            t3 =tiledlayout(p3,1,1);
            handles.ax3=axes(t3); % petit plot P,T
            hold on
            box on
            %handles.ax4=subplot(4,18,[40:41,58:59]);  % petit plot P,S
            %handles.ax4=subplot(4,18,[41:42,59:61]);  % petit plot P,S
            p4 =uipanel('Position',[.23 .01 .215 .54]);
            t4 =tiledlayout(p4,1,1);
            handles.ax4=axes(t4); % petit plot P,T
            hold on
            box on
            %              handles.ax1=app.axes1_1;
            %              handles.ax2=app.axes1;
            %              handles.ax3=app.axes1_2;
            %              handles.ax4=app.axes1_3;
            % Update handles structure
            guidata(hObject, handles);
            waitfor(handles.figure2)
        end

        % Button pushed function: NEXT
        function NEXT_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to NEXT (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %keyboard
            prof_number=get(handles.profile,'String');
            %keyboard
            if isempty(prof_number)==0
                delta_pas=[];
                ispas=0;
                if isempty(strfind(prof_number,':'))==0||isempty(strfind(prof_number,','))
                    
                    r=prof_number;
                    r=strrep(r,']','');
                    r=strrep(r,'[','');
                    [d1,poub]=strtok(r,':');
                    while isempty(strfind(r,':'))==0
                        ispas=1;
                        [d,r]=strtok(r,':');
                        if isempty(r)==0
                            r=r(2:end);
                        end
                    end
                    while isempty(strfind(r,','))==0
                        [d,r]=strtok(r,',');
                        if isempty(r)==0
                            r=r(2:end);
                        end
                    end
                    
                    if isempty(strfind(r,'end'))==0
                        r=strrep(r,'end',num2str(length(handles.file_list)));
                    end
                    if ispas==1
                        delta_pas=str2double(r)-str2double(d1)+1;
                    end
                    k=str2double(r)+1;
                else
                    k=str2num(prof_number)+1;
                end
                if k<1
                    k=1;
                end
                if k>length(handles.file_list)
                    k=length(handles.file_list);
                end
                prof_number=num2str(k);
                if isempty(delta_pas)==0
                    prof_number=[prof_number ':' num2str(k+delta_pas-1)]
                end
                set(handles.profile,'String',prof_number);
                
                profile_Callback(app,event)
                
            else
                set(handles.profile,'String','1');
                profile_Callback(app,event)
            end
        end

        % Button pushed function: cancel
        function cancel_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cancel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            profile_Callback(app,event)
        end

        % Value changed function: new_flag
        function new_flag_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to new_flag (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of new_flag as text
            %        str2double(get(hObject,'String')) returns contents of new_flag as a double
            parameters=get(handles.parameter,'String');
            choice=get(handles.parameter,'Value');
            if strcmp(parameters{choice},'Temperature')||strcmp(parameters{choice},'Temperature (CNDC)')||strcmp(parameters{choice},'Salinity')||strcmp(parameters{choice},'ThetaS')||strcmp(parameters{choice},'Sigma0')||strcmp(parameters{choice},'Oxygene')||strcmp(parameters{choice},'Oxygene(Adjusted)')
                new_flag=str2double(get(hObject,'String'));
                if  handles.KEEPZOOM==0
                    BackgroundColor_vec=[0 1 0; 1 1 0; 1 0 1; 1 0 0];
                else
                    BackgroundColor_vec=[0 1 0; 1 1 0; 1 0.7 0; 1 0 0];
                end
                set(hObject,'BackgroundColor',BackgroundColor_vec(new_flag,:))
            else
                helpdlg(['Flags cannot be changed for ' parameters{choice}])
            end
        end

        % Value changed function: parameter
        function parameter_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to parameter (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns parameter contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from parameter
            parameters=get(handles.parameter,'String');
            choice=get(handles.parameter,'Value');
            %disp('je passe dans Parameter callback1')
            if isfield(handles,'choice_previous')==1
                handles.choice_previous
                choice
                %keyboard
                if handles.KEEPZOOM==1
                    if handles.choice_previous~=choice
                        set(handles.ax2,'XLimMode','auto')
                        if choice==7|choice==13|handles.choice_previous==7|handles.choice_previous==13
                            set(handles.ax2,'YLimMode','auto')
                            
                        end
                        %zoom out
                    end
                end
                if handles.choice_previous~=8&&handles.choice_previous~=14 % oxygene or oxygene adjusted
               	   if choice==8||choice==14
               	      handles.choice_previous=choice;
                      guidata(hObject,handles);
                      %disp('je passe dans Parameter callback1a')
               		 % profile_Callback(app,event)
                     
                     profile_Callback(app,event)
                     return
                     
                   end
                else
                    %if choice~=3&&choice~=13
                    if choice~=8&&choice~=14
                        handles.choice_previous=choice;
                        guidata(hObject,handles);
                        %disp('je passe dans Parameter callback1b')
                        profile_Callback(app, event)
                        return
                        
                    end
                end
                
            end
            thetitle='';
            %disp('je passe dans Parameter callback2')
            prof_number=get(handles.profile,'String');
            if handles.KEEPZOOM==0
                zoom out
            else
                set(handles.ax2,'XLimMode','auto')
            end
            %zoom out
            if isempty(prof_number)
                set(handles.profile,'String','1');
                %profile_Callback(app,event)
                profile_Callback(app,event)
                return
            else
                 
                axes(handles.ax2)
                cla
                axes(handles.ax1)
                if handles.FLAG==0
               	   markerstr='none';
                else
               	   markerstr='+';
                end
                markerstr
                if isempty(handles.F.latitude.data)==0
                    cla(gca)
                    [t,r]=strtok(handles.file_list,'_');
                    r=strrep(r,'_','');
                    r=strrep(r,'.nc','');
                    r=strrep(r,'D','');
                    
                    handles.F.min_cy=min(str2num(char(r)));
                    handles.F.max_cy=max(str2num(char(r)));
                    
                    if isempty(handles.F2)==0
                        cla(gca)
                        handles.F2.min_cy=min(str2num(char(r)));
                        handles.F2.max_cy=max(str2num(char(r)));
                        plot_traj_glob_gui(handles.F2,handles.topo)
                    else
                        plot_traj_glob_gui(handles.F,handles.topo)
                    end
                    axes(handles.ax2)
                    
                    %handles.F.psal.data
                    parameters{choice}
                    if strcmp(parameters{choice},'Temperature')
                        figure(handles.figure2)
                        cla(handles.ax2)
                        if handles.KEEPZOOM==0
                            zoom out
                        else
                            set(handles.ax2,'XLimMode','auto')
                        end
                        %zoom out
                        n_prof=size(handles.F.temp.data,1);
                        
                        if isempty(handles.F2)==0
                            n_prof2=size(handles.F2.temp.data,1);
                            plot_profile_with_flag_v2(handles.F2,'temp','pres',[1:n_prof2],'LineWidth',0.5,'Marker','none');
                        end
                        thetitle=plot_profile_with_flag_v2(handles.F,'temp','pres',[1:n_prof],'LineWidth',0.5,'Marker',markerstr);
                        %title(thetitle,'')
                        handles.p0.Title=strjoin(thetitle);
                        axes(handles.ax3)
                        cla(gca)
                        plot_profile_with_flag_v2(handles.F3,'temp','pres',[1:n_prof],'LineWidth',0.5);
                        axes(handles.ax4)
                        cla(gca)
                        plot_profile_with_flag_v2(handles.F3,'psal','pres',[1:n_prof],'LineWidth',0.5);
                        axes(handles.ax2)
                    elseif strcmp(parameters{choice},'Temperature (CNDC)')
                        figure(handles.figure2)
                        if isfield(handles.F,'doxy')
                            cla(gca)
                            %zoom out
                            if handles.KEEPZOOM==0
                                zoom out
                            else
                                set(handles.ax2,'XLimMode','auto')
                            end
                            n_prof=size(handles.F.temp.data,1);
                            if isempty(handles.F2)==0
                                n_prof2=size(handles.F2.temp.data,1);
                                
                                plot_profile_with_flag_v2(handles.F2,'temp_cndc','pres',[1:n_prof2],'LineWidth',0.5,'Marker','none');
                                %plot_profile_with_flag_v2(handles.F2,'temp_cndc','pres',[1:n_prof2],'LineWidth',0.5,'Marker','none');
                            end
                            %disp('test')
                            %markerstr
                            %handles.F.tempcndc_temp.data=abs(handles.F.temp_cndc.data-handles.F.temp.data);
                            %handles.F.tempcndc_temp_qc.data=handles.F.temp_cndc_qc.data;
                            thetitle= plot_profile_with_flag_v2(handles.F,'temp_cndc','pres',[1:n_prof],'LineWidth',0.5,'Marker',markerstr);
                            %thetitle= plot_profile_with_flag_v2(handles.F,'psal','pres',[1:n_prof],'LineWidth',0.5,'Marker','none');
                            %title(thetitle)
                            handles.p0.Title=strjoin(thetitle);
                            axes(handles.ax3)
                            cla(gca)
                            plot_profile_with_flag_v2(handles.F3,'temp','pres',[1:n_prof],'LineWidth',0.5);
                            axes(handles.ax4)
                            cla(gca)
                            plot_profile_with_flag_v2(handles.F3,'psal','pres',[1:n_prof],'LineWidth',0.5);
                            axes(handles.ax2)
                        else
                            xlabel('TEMP_CNDC : no value','interpreter','none')
                        end
                    elseif strcmp(parameters{choice},'Salinity')
                        figure(handles.figure2)
                        cla(gca)
                        %zoom out
                        if handles.KEEPZOOM==0
                            zoom out
                        else
                            set(handles.ax2,'XLimMode','auto')
                        end
                        n_prof=size(handles.F.temp.data,1);
                        if isempty(handles.F2)==0
                            n_prof2=size(handles.F2.temp.data,1);
                            
                            plot_profile_with_flag_v2(handles.F2,'psal','pres',[1:n_prof2],'LineWidth',0.5,'Marker','none');
                        end
                        %disp('test')
                        %markerstr
                        thetitle= plot_profile_with_flag_v2(handles.F,'psal','pres',[1:n_prof],'LineWidth',0.5,'Marker',markerstr);
                        %thetitle= plot_profile_with_flag_v2(handles.F,'psal','pres',[1:n_prof],'LineWidth',0.5,'Marker','none');
                        %title(thetitle)
                        handles.p0.Title=strjoin(thetitle);
                        axes(handles.ax3)
                        cla(gca)
                        plot_profile_with_flag_v2(handles.F3,'temp','pres',[1:n_prof],'LineWidth',0.5);
                        axes(handles.ax4)
                        cla(gca)
                        plot_profile_with_flag_v2(handles.F3,'psal','pres',[1:n_prof],'LineWidth',0.5);
                        axes(handles.ax2)
                    elseif strcmp(parameters{choice},'Oxygene')
                        figure(handles.figure2)
                        if isfield(handles.F,'doxy')
                            cla(gca)
                            if handles.KEEPZOOM==0
                                zoom out
                            else
                                set(handles.ax2,'XLimMode','auto')
                            end
                            %zoom out
                            n_prof=size(handles.F.pres.data,1);
                            if isempty(handles.F2)==0
                                n_prof2=size(handles.F2.pres.data,1);
                                plot_profile_with_flag_v2(handles.F2,'doxy','pres',[1:n_prof2],'LineWidth',0.5,'Marker','none');
                            end
                            thetitle= plot_profile_with_flag_v2(handles.F,'doxy','pres',[1:n_prof],'LineWidth',0.5,'Marker',markerstr);
                            %thetitle= plot_profile_with_flag_v2(handles.F,'psal','pres',[1:n_prof],'LineWidth',0.5,'Marker','none');
                            %title(thetitle)
                            handles.p0.Title=strjoin(thetitle);
                            axes(handles.ax3)
                            cla(gca)
                            plot_profile_with_flag_v2(handles.F3,'temp','pres',[1:n_prof],'LineWidth',0.5);
                            axes(handles.ax4)
                            cla(gca)
                            plot_profile_with_flag_v2(handles.F3,'psal','pres',[1:n_prof],'LineWidth',0.5);
                            axes(handles.ax2)
                        else
                            xlabel('DOXY : no value')
                        end
                    elseif strcmp(parameters{choice},'Ptemp')
                       figure(handles.figure2) 
               		   % keyboard
                       cla(gca)
                       %zoom out
                       if handles.KEEPZOOM==0
                           zoom out
                       else
                           set(handles.ax2,'XLimMode','auto')
                       end
                       n_prof=size(handles.F.temp.data,1);
                       if isempty(handles.F2)==0
                           n_prof2=size(handles.F2.temp.data,1);
                           handles.F2.ptemp.data=sw_ptmp(handles.F2.psal.data,handles.F2.temp.data,handles.F2.pres.data,0);
                           handles.F2.ptemp_qc.data=handles.F2.psal_qc.data;
                           plot_profile_with_flag_v2(handles.F2,'ptemp','pres',[1:n_prof2],'LineWidth',0.5,'Marker','none');
                       end
                       handles.F.ptemp=handles.F.temp; %init
                       handles.F.ptemp_qc=handles.F.temp_qc; % init
                       handles.F.ptemp.data=sw_ptmp(handles.F.psal.data,handles.F.temp.data,handles.F.pres.data,0);
                       handles.F.ptemp_qc.data=handles.F.psal_qc.data;
                       thetitle= plot_profile_with_flag_v2(handles.F,'ptemp','pres',[1:n_prof],'LineWidth',0.5,'Marker',markerstr);
                       %thetitle= plot_profile_with_flag_v2(handles.F,'ptemp','pres',[1:n_prof],'LineWidth',0.5,'Marker','none');
                       %title(thetitle)
                       handles.p0.Title=strjoin(thetitle);
                       axes(handles.ax3)
                       cla(gca)
                       plot_profile_with_flag_v2(handles.F3,'temp','pres',[1:n_prof],'LineWidth',0.5);
                       axes(handles.ax4)
                       cla(gca)
                       plot_profile_with_flag_v2(handles.F3,'psal','pres',[1:n_prof],'LineWidth',0.5);
                       axes(handles.ax2)
                    elseif strcmp(parameters{choice},'Sigma0')
                        figure(handles.figure2)
               		   % keyboard
                       cla(handles.ax2)
                       %zoom out
                       if handles.KEEPZOOM==0
                           zoom out
                       else
                           set(handles.ax2,'XLimMode','auto')
                       end
                       n_prof=size(handles.F.temp.data,1);
                       
                       if isempty(handles.F2)==0
                           n_prof2=size(handles.F2.temp.data,1);
                           ptemp=sw_ptmp(handles.F2.psal.data,handles.F2.temp.data,handles.F2.pres.data,0);
                           handles.F2.sigma0.data=sw_dens0(handles.F2.psal.data,ptemp)-1000;
                           handles.F2.sigma0_qc.data=handles.F2.psal_qc.data;
                           plot_profile_with_flag_v2(handles.F2,'sigma0','pres',[1:n_prof2],'LineWidth',0.5,'Marker','none');
                       end
                       ptemp=sw_ptmp(handles.F.psal.data,handles.F.temp.data,handles.F.pres.data,0);
                       handles.F.sigma0=handles.F.temp;% init
                       handles.F.sigma0_qc=handles.F.temp_qc; % init
                       handles.F.sigma0.data=sw_dens0(handles.F.psal.data,ptemp)-1000;
                       handles.F.sigma0_qc.data=handles.F.psal_qc.data;
                       thetitle= plot_profile_with_flag_v2(handles.F,'sigma0','pres',[1:n_prof],'LineWidth',0.5,'Marker',markerstr);

                       %thetitle= plot_profile_with_flag_v2(handles.F,'sigma0','pres',[1:n_prof],'LineWidth',0.5,'Marker','none');
                       %title(thetitle)
                       handles.p0.Title=strjoin(thetitle);
                       axes(handles.ax3)
                       cla(gca)
                       plot_profile_with_flag_v2(handles.F3,'temp','pres',[1:n_prof],'LineWidth',0.5);
                       axes(handles.ax4)
                       cla(gca)
                       plot_profile_with_flag_v2(handles.F3,'psal','pres',[1:n_prof],'LineWidth',0.5);
                       axes(handles.ax2)
                    elseif strcmp(parameters{choice},'ThetaS')
                       figure(handles.figure2)
               		   % keyboard
                       cla(gca)
                       if handles.KEEPZOOM==0
                           zoom out
                       else
                           set(handles.ax2,'XLimMode','auto')
                       end
                       %zoom out
                       n_prof=size(handles.F.temp.data,1);
                       if isempty(handles.F2)==0
                           n_prof2=size(handles.F2.temp.data,1);
                           handles.F2.ptemp.data=sw_ptmp(handles.F2.psal.data,handles.F2.temp.data,handles.F2.pres.data,0);
                           plot_profile_with_flag_v2(handles.F2,'psal','ptemp',[1:n_prof2],'LineWidth',0.5,'Marker','none');
                       end
                       handles.F.ptemp=handles.F.temp;
                       handles.F.ptemp.data=sw_ptmp(handles.F.psal.data,handles.F.temp.data,handles.F.pres.data,0);
                       thetitle= plot_profile_with_flag_v2(handles.F,'psal','ptemp',[1:n_prof],'LineWidth',0.5,'Marker',markerstr);
                       %title(thetitle)
                       handles.p0.Title=strjoin(thetitle);
                       axes(handles.ax3)
                       cla(gca)
                       plot_profile_with_flag_v2(handles.F3,'temp','pres',[1:n_prof],'LineWidth',0.5);
                       axes(handles.ax4)
                       cla(gca)
                       plot_profile_with_flag_v2(handles.F3,'psal','pres',[1:n_prof],'LineWidth',0.5);
                       axes(handles.ax2)
                    elseif strcmp(parameters{choice},'Temperature(Adjusted)')
                        figure(handles.figure2)
                        cla(gca)
                        if handles.KEEPZOOM==0
                            zoom out
                        else
                            set(handles.ax2,'XLimMode','auto')
                        end
                        %zoom out
                        %keyboard
                        n_prof=size(handles.F.temp.data,1);
                        
                        if isempty(handles.F2)==0
                            n_prof2=size(handles.F2.temp.data,1);
                            plot_profile_with_flag_v2(handles.F2,'temp_adjusted','pres_adjusted',[1:n_prof2],'LineWidth',0.5,'Marker','none');
                        end
                        thetitle=plot_profile_with_flag_v2(handles.F,'temp_adjusted','pres_adjusted',[1:n_prof],'LineWidth',0.5,'Marker',markerstr);
                        axes(handles.ax1)
                        %title(thetitle)
                        handles.p0.Title=strjoin(thetitle);
                        axes(handles.ax3)
                        cla(gca)
                        plot_profile_with_flag_v2(handles.F3,'temp','pres',[1:n_prof],'LineWidth',0.5);
                        axes(handles.ax4)
                        cla(gca)
                        plot_profile_with_flag_v2(handles.F3,'psal','pres',[1:n_prof],'LineWidth',0.5);
                        axes(handles.ax2)
                    elseif strcmp(parameters{choice},'Ptemp(Adjusted)')
                       figure(handles.figure2) 
               		   % keyboard
                       cla(gca)
                       %zoom out
                       if handles.KEEPZOOM==0
                           zoom out
                       else
                           set(handles.ax2,'XLimMode','auto')
                       end
                       n_prof=size(handles.F.temp.data,1);
                       if isempty(handles.F2)==0
                           n_prof2=size(handles.F2.temp.data,1);
                           handles.F2.ptemp_adjusted.data=sw_ptmp(handles.F2.psal_adjusted.data,handles.F2.temp_adjusted.data,handles.F2.pres_adjusted.data,0);
                           handles.F2.ptemp_adjusted_qc.data=handles.F2.psal_adjusted_qc.data;
                           plot_profile_with_flag_v2(handles.F2,'ptemp_adjusted','pres_adjusted',[1:n_prof2],'LineWidth',0.5,'Marker','none');
                       end
                       handles.F.ptemp_adjusted=handles.F.temp_adjusted;
                       handles.F.ptemp_adjusted_qc=handles.F.temp_adjusted_qc;
                       handles.F.ptemp_adjusted.data=sw_ptmp(handles.F.psal_adjusted.data,handles.F.temp_adjusted.data,handles.F.pres_adjusted.data,0);
                       handles.F.ptemp_adjusted_qc.data=handles.F.psal_adjusted_qc.data;
                       thetitle= plot_profile_with_flag_v2(handles.F,'ptemp_adjusted','pres_adjusted',[1:n_prof],'LineWidth',0.5,'Marker',markerstr);
                       %title(thetitle)
                       handles.p0.Title=strjoin(thetitle);
                       axes(handles.ax3)
                       cla(gca)
                       plot_profile_with_flag_v2(handles.F3,'temp','pres',[1:n_prof],'LineWidth',0.5);
                       axes(handles.ax4)
                       cla(gca)
                       plot_profile_with_flag_v2(handles.F3,'psal','pres',[1:n_prof],'LineWidth',0.5);
                       axes(handles.ax2)
                    elseif strcmp(parameters{choice},'Salinity(Adjusted)')
                        figure(handles.figure2)
                        %keyboard
                        cla(gca)
                        %zoom out
                        if handles.KEEPZOOM==0
                            zoom out
                        else
                            set(handles.ax2,'XLimMode','auto')
                        end
                        n_prof=size(handles.F.temp.data,1);
                        if isempty(handles.F2)==0
                            n_prof2=size(handles.F2.temp.data,1);
                            plot_profile_with_flag_v2(handles.F2,'psal_adjusted','pres_adjusted',[1:n_prof2],'LineWidth',0.5,'Marker','none');
                        end
                        thetitle= plot_profile_with_flag_v2(handles.F,'psal_adjusted','pres_adjusted',[1:n_prof],'LineWidth',0.5,'Marker',markerstr);
                        %title(thetitle)
                        handles.p0.Title=strjoin(thetitle);
                        axes(handles.ax3)
                        cla(gca)
                        plot_profile_with_flag_v2(handles.F3,'temp','pres',[1:n_prof],'LineWidth',0.5);
                        axes(handles.ax4)
                        cla(gca)
                        plot_profile_with_flag_v2(handles.F3,'psal','pres',[1:n_prof],'LineWidth',0.5);
                        axes(handles.ax2)
                    elseif strcmp(parameters{choice},'Oxygene(Adjusted)')
                        figure(handles.figure2)
                        cla(gca)
                        if handles.KEEPZOOM==0
                            zoom out
                        else
                            set(handles.ax2,'XLimMode','auto')
                        end
                        %zoom out
                        n_prof=size(handles.F.pres.data,1);
                        if isempty(handles.F2)==0
                            n_prof2=size(handles.F2.pres.data,1);
                            plot_profile_with_flag_v2(handles.F2,'doxy_adjusted','pres',[1:n_prof2],'LineWidth',0.5,'Marker','none');
                        end
                        thetitle= plot_profile_with_flag_v2(handles.F,'doxy_adjusted','pres',[1:n_prof],'LineWidth',0.5,'Marker',markerstr);
                        %title(thetitle)
                        handles.p0.Title=strjoin(thetitle);
                        axes(handles.ax3)
                        cla(gca)
                        plot_profile_with_flag_v2(handles.F3,'temp','pres',[1:n_prof],'LineWidth',0.5);
                        axes(handles.ax4)
                        cla(gca)
                        plot_profile_with_flag_v2(handles.F3,'psal','pres',[1:n_prof],'LineWidth',0.5);
                        axes(handles.ax2)
                    elseif strcmp(parameters{choice},'ThetaS(Adjusted)')
                       figure(handles.figure2) 
               		   % keyboard
                       cla(gca)
                       if handles.KEEPZOOM==0
                           zoom out
                       else
                           set(handles.ax2,'XLimMode','auto')
                       end
                       %zoom out
                       n_prof=size(handles.F.temp.data,1);
                       if isempty(handles.F2)==0
                           n_prof2=size(handles.F2.temp.data,1);
                           handles.F2.ptemp_adjusted.data=sw_ptmp(handles.F2.psal_adjusted.data,handles.F2.temp_adjusted.data,handles.F2.pres_adjusted.data,0);
                           plot_profile_with_flag_v2(handles.F2,'psal_adjusted','ptemp_adjusted',[1:n_prof2],'LineWidth',0.5,'Marker','none');
                       end
                       handles.F.ptemp_adjusted=handles.F.temp_adjusted;
                       handles.F.ptemp_adjusted.data=sw_ptmp(handles.F.psal_adjusted.data,handles.F.temp_adjusted.data,handles.F.pres_adjusted.data,0);
                       thetitle= plot_profile_with_flag_v2(handles.F,'psal_adjusted','ptemp_adjusted',[1:n_prof],'LineWidth',0.5,'Marker',markerstr);
                       %title(thetitle)
                       handles.p0.Title=strjoin(thetitle);
                       axes(handles.ax3)
                       cla(gca)
                       plot_profile_with_flag_v2(handles.F3,'temp','pres',[1:n_prof],'LineWidth',0.5);
                       axes(handles.ax4)
                       cla(gca)
                       plot_profile_with_flag_v2(handles.F3,'psal','pres',[1:n_prof],'LineWidth',0.5);
                       axes(handles.ax2)
                    end
                end
            end
            handles.thetitle=thetitle;
            handles.choice_previous=choice;
            
            guidata(hObject,handles);
            Addsigma0CheckBoxValueChanged(app,event)
            AddTempCheckBoxValueChanged(app,event)

        end

        % Button pushed function: previous
        function previous_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to previous (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            prof_number=get(handles.profile,'String');
            if isempty(prof_number)==0
                if isempty(strfind(prof_number,':'))==0||isempty(strfind(prof_number,','))
                    [d,r]=strtok(prof_number,':')
                    [d,r]=strtok(d,',');
                    d=strrep(d,'[','');
                    k=str2double(d)-1;
                else
                    k=str2num(prof_number)-1;
                end
                if k<1
                    k=1;
                end
                if k>length(handles.file_list)
                    k=length(handles.file_list);
                end
                prof_number=num2str(k);   set(handles.profile,'String',prof_number);
                profile_Callback(app,event)
                
            else
                set(handles.profile,'String','1');
                profile_Callback(app,event)
            end
        end

        % Button pushed function: pushbutton6
        function printfig_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            disp('print figure ...')
            
            %orient landscape
            if length(handles.thetitle)==2
                thetitlestok=[handles.thetitle{1},handles.thetitle{2}];
            else
                thetitlestok=  handles.thetitle{1};
            end
            ii=strfind(thetitlestok,'cycle');
            cut_thetitle=thetitlestok(ii:end);
            if isempty(cut_thetitle)==0
                
                [r,t]=strtok(cut_thetitle,',');
                cut_thetitle=strrep(r,'cycle:','');
                cut_thetitle=strrep(cut_thetitle,'cycles:','');
                cut_thetitle=strrep(cut_thetitle,' ','');
                cut_thetitle=strrep(cut_thetitle,'-','_');
                cut_thetitle=strrep(cut_thetitle,'A','');
                
                dirname=[handles.DIR_PLOT '/change_flag/' handles.floatname '/'];
                if exist(dirname)==0
                    mkdir(dirname)
                end
                %filename=[dirname handles.floatname '_changeflag_' cut_thetitle '.pdf']
                %print(handles.figure1,filename,'-dpdf')
                filename=[dirname handles.floatname '_changeflag_' cut_thetitle '.png']
                set(handles.figure2,'papertype','usletter','paperunits','inches','paperorientation','landscape','paperposition',[.25,.75,9.5,8]);
                print(handles.figure2,filename,'-dpng')
            end
        end

        % Value changed function: profile
        function profile_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to profile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of profile as text
            %        str2double(get(hObject,'String')) returns contents of profile as a double
            
            vert_sampl=get(handles.vertical_sampling,'String');
            choice_vert=get(handles.vertical_sampling,'Value');
            %disp('je passe dans Profile callback1')
            parameters=get(handles.parameter,'String');
            choice=get(handles.parameter,'Value');
           % keyboard
            
            if ~isempty(strfind(vert_sampl{choice_vert},'Primary Sampling'))
                vertical_sampling_scheme='Primary sampling';
            end
            if ~isempty(strfind(vert_sampl{choice_vert},'Near Surface Sampling'))
                vertical_sampling_scheme='Near-surface sampling';
            end
            k=(get(handles.profile,'String'));
            [d,r]=strtok(k,':');
            if str2double(d)<1
                k=['[1' r];
            end
            [d,r]=strtok(k,':');
            if isempty(strfind(r,'end'))==0
                r=strrep(r,'end',num2str(length(handles.file_list)));
            else
                if str2double(strrep(r,':',''))>length(handles.file_list)
                    k=[ d ':end'];
                end
            end
            
            %eval(['thelist=handles.file_list(' k ');']);
            
            % if k<1
            %     k=1;
            % end
            % if k>length(handles.file_list)
            %     k=length(handles.file_list);
            % end
            
            eval(['thelist=handles.file_list(' k ');']);
            if strcmp(parameters{choice},'Oxygene')||strcmp(parameters{choice},'Oxygene(Adjusted)')
                %if isfield(handles.F,'doxy')
                eval(['thelist=handles.file_listB(' k ');']);
                %end
            end
            eval(['thelistC=handles.file_list(' k ');']);
            %keyboard
            if length(thelist)>1
                Param='';
                %vertical_sampling_scheme='Primary sampling';
                display('Ploting data...')
                [F,Dim,thelist_ext]=create_multi_from_filelist(handles.floatname,handles.dacname,handles.DIR_FTP,thelist,vertical_sampling_scheme,Param);
                [F3,Dim3,thelist_ext3]=create_multi_from_filelist(handles.floatname,handles.dacname,handles.DIR_FTP,thelistC,vertical_sampling_scheme,Param);
                G='';
                F2='';
                thelist=thelist_ext
            else
                
                k=[str2num(k)-handles.NB_PROF:str2num(k)+handles.NB_PROF];
                k(k<1)=1;
                k(k>length(handles.file_list))=length(handles.file_list);
                k=unique(k);
                kstr=[num2str(k(1)) ':' num2str(k(end))];
                eval(['thelist2=handles.file_list(' kstr ');']);
                if strcmp(parameters{choice},'Oxygene')||strcmp(parameters{choice},'Oxygene(Adjusted)')
                    %if isfield(handles.F,'doxy')
                    eval(['thelist2=handles.file_listB(' kstr ');']); 
                    %end
                end
                Param='';
                
                [F,Dim,G] = read_netcdf_allthefile([handles.DIR_FTP handles.dacname '/' handles.floatname '/profiles/' thelist{1}]);
                [F2,Dim2,thelist_ext2]=create_multi_from_filelist(handles.floatname,handles.dacname,handles.DIR_FTP,thelist2,vertical_sampling_scheme,Param);
                [F3,Dim3,G3] = read_netcdf_allthefile([handles.DIR_FTP handles.dacname '/' handles.floatname '/profiles/' thelistC{1}]);
                is_primary=findstr_tab(F.vertical_sampling_scheme.data,vertical_sampling_scheme);
                [F,Dim] = extract_profile_dim(F,Dim,'N_PROF',is_primary);
                [F3,Dim3] = extract_profile_dim(F3,Dim3,'N_PROF',is_primary);
                F2 = format_flags_char2num(F2);
                F2 = replace_fill_bynan(F2);
                %disp('je passe dans Profile callback2')
                
                
            end
            
            F = format_flags_char2num(F);
            F = replace_fill_bynan(F);
            F3 = format_flags_char2num(F3);
            F3 = replace_fill_bynan(F3);
            handles.thelist=thelist;
            handles.thelistC=thelistC;
            handles.F=F;
            handles.F2=F2;
            handles.F3=F3;
            handles.Dim=Dim;
            handles.G=G;
            guidata(hObject,handles);
            parameter_Callback(app,event)
            Addsigma0CheckBoxValueChanged(app,event)
            AddTempCheckBoxValueChanged(app,event)

            %parameter_Callback(app,event)
        end

        % Value changed function: selection
        function selection_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to selection (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %keyboard
            parameters=get(handles.selection,'String');
            choice=get(handles.selection,'Value');
            
            new_flag=get(handles.new_flag,'String');
            if handles.KEEPZOOM==0
                colormarker={[0 1 0];[1 1 0];[1 0 1];[1 0 0]}
            else
                colormarker={[0 1 0];[1 1 0];[1 0.7 0];[1 0 0]};
            end
            xx=[];
            yy=[];
            
            %zoom out
            zoom off
            if handles.VPN==0
                axes(handles.ax2)
                if ~isempty(strfind(parameters{choice},'Rectangle'))
                    [h,x,y]=selectdata2('selectionmode','Rect','Pointer','cross');
                end
                if ~isempty(strfind(parameters{choice},'Single Point'))
                    [h,x,y]=selectdata2('selectionmode','Closest','Pointer','cross');
                    
                end
                
                for l=1:length(x)
                    xx=[xx x{l}']; yy=[yy y{l}'];
                end
                min_x=min(xx);
                min_y=min(yy);
                max_x=max(xx);
                max_y=max(yy);
            end
            if handles.VPN==1
                if ~isempty(strfind(parameters{choice},'Rectangle'))
                    axes(handles.ax2)
                    [xx1,yy1]=ginput(1);plot(xx1,yy1,'+')
                    [xx2,yy2]=ginput(1);plot([xx1 xx2],[yy1 yy2],'-+')
                    [xx3,yy3]=ginput(1);plot([xx1 xx2 xx3],[yy1 yy2 yy3],'-+')
                    [xx4,yy4]=ginput(1);plot([xx1 xx2 xx3 xx4 xx1],[yy1 yy2 yy3 yy4 yy1],'-+')
                    xx=[xx1 xx2 xx3 xx4];
                    yy=[yy1 yy2 yy3 yy4];
                    min_x=min(xx);
                    min_y=min(yy);
                    max_x=max(xx);
                    max_y=max(yy);
                end
                
                if ~isempty(strfind(parameters{choice},'Single Point'))
                    [h,x,y]=selectdata('selectionmode','Closest','Pointer','cross');
                    for l=1:length(x)
                        xx=[xx x{l}']; yy=[yy y{l}'];
                    end
                    min_x=min(xx);
                    min_y=min(yy);
                    max_x=max(xx);
                    max_y=max(yy);
                end
            end
            parameters=get(handles.parameter,'String');
            choice=get(handles.parameter,'Value');
            prof_number=get(handles.profile,'String');
            
            if isempty(prof_number)
                set(handles.profile,'String','1');
                disp('here')
                profile_Callback(app,event)
            else
                [d,r]=strtok(prof_number,':');d=str2num(d);
                if isempty(r)==0
                    r=strrep(r,'end',num2str(size(handles.F.cycle_number.data,1)));
                    r=str2num(strrep(r,':',''));
                else
                    r=d;
                end
                %keyboard
                for k=1:length(handles.thelist)
                    %     for k=1:r-d+1
                    if ~isempty(min_x)|~isempty(max_x)|~isempty(min_y)|~isempty(max_y)
                        if strcmp(parameters{choice},'Temperature')
                            isub=find(handles.F.temp.data(k,:)<=max_x&handles.F.temp.data(k,:)>=min_x&handles.F.pres.data(k,:)<=max_y&handles.F.pres.data(k,:)>=min_y);
                            hold on
                            %keyboard
                            %plot(handles.F.temp.data(k,isub),handles.F.pres.data(k,isub),[colormarker{str2num(new_flag)} '+'])
                            plot(handles.F.temp.data(k,isub),handles.F.pres.data(k,isub),'+','Color',colormarker{str2num(new_flag)})
                            
                            %handles.F.temp_qc_new=handles.F.temp_qc;
                            handles.F.temp_qc.data(k,isub)=str2num(new_flag);
                            
                        elseif strcmp(parameters{choice},'Temperature (CNDC)')
                            isub=find(handles.F.temp_cndc.data(k,:)<=max_x&handles.F.temp_cndc.data(k,:)>=min_x&handles.F.pres.data(k,:)<=max_y&handles.F.pres.data(k,:)>=min_y);
                            hold on
                            
                            %plot(handles.F.psal.data(k,isub),handles.F.pres.data(k,isub),[colormarker{str2num(new_flag)} '+'])
                            plot(handles.F.temp_cndc.data(k,isub),handles.F.pres.data(k,isub), '+','Color',colormarker{str2num(new_flag)})
                            
                            % handles.F.psal_qc_new=handles.F.psal_qc;
                            handles.F.temp_cndc_qc.data(k,isub)=str2num(new_flag);
                            
                        elseif strcmp(parameters{choice},'Salinity')
                            isub=find(handles.F.psal.data(k,:)<=max_x&handles.F.psal.data(k,:)>=min_x&handles.F.pres.data(k,:)<=max_y&handles.F.pres.data(k,:)>=min_y);
                            hold on
                            
                            %plot(handles.F.psal.data(k,isub),handles.F.pres.data(k,isub),[colormarker{str2num(new_flag)} '+'])
                            plot(handles.F.psal.data(k,isub),handles.F.pres.data(k,isub), '+','Color',colormarker{str2num(new_flag)})
                            
                            % handles.F.psal_qc_new=handles.F.psal_qc;
                            handles.F.psal_qc.data(k,isub)=str2num(new_flag);
                            
                        elseif strcmp(parameters{choice},'ThetaS')
                            ptemp = sw_ptmp(handles.F.psal.data,handles.F.temp.data,handles.F.pres.data,0);
                            isub=find(handles.F.psal.data(k,:)<=max_x&handles.F.psal.data(k,:)>=min_x&ptemp(k,:)<=max_y&ptemp(k,:)>=min_y);
                            hold on
                            %plot(handles.F.psal.data(k,isub),ptemp(k,isub),[colormarker{str2num(new_flag)} '+'])
                            plot(handles.F.psal.data(k,isub),ptemp(k,isub), '+','Color',colormarker{str2num(new_flag)})
                            
                            % handles.F.psal_qc_new=handles.F.psal_qc;
                            handles.F.psal_qc.data(k,isub)=str2num(new_flag);
                        elseif strcmp(parameters{choice},'Sigma0')
                            ptemp = sw_ptmp(handles.F.psal.data,handles.F.temp.data,handles.F.pres.data,0);
                            sigma0= sw_dens0(handles.F.psal.data,ptemp)-1000;
                            isub=find(sigma0(k,:)<=max_x&sigma0(k,:)>=min_x&handles.F.pres.data(k,:)<=max_y&handles.F.pres.data(k,:)>=min_y);
                            hold on


                            %plot(sigma0(k,isub),handles.F.pres.data(k,isub),[colormarker{str2num(new_flag)} '+'])
                            plot(sigma0(k,isub),handles.F.pres.data(k,isub), '+','Color',colormarker{str2num(new_flag)})
                            % handles.F.psal_qc_new=handles.F.psal_qc;
                            handles.F.psal_qc.data(k,isub)=str2num(new_flag);
                        elseif strcmp(parameters{choice},'Oxygene')
                            isub=find(handles.F.doxy.data(k,:)<=max_x&handles.F.doxy.data(k,:)>=min_x&handles.F.pres.data(k,:)<=max_y&handles.F.pres.data(k,:)>=min_y);
                            hold on
                            
                            %plot(handles.F.doxy.data(k,isub),handles.F.pres.data(k,isub),[colormarker{str2num(new_flag)} '+'])
                            plot(handles.F.doxy.data(k,isub),handles.F.pres.data(k,isub), '+','Color',colormarker{str2num(new_flag)})
                            % handles.F.psal_qc_new=handles.F.psal_qc;
                            handles.F.doxy_qc.data(k,isub)=str2num(new_flag);
                        elseif strcmp(parameters{choice},'Oxygene(Adjusted)')
                            isub=find(handles.F.doxy_adjusted.data(k,:)<=max_x&handles.F.doxy_adjusted.data(k,:)>=min_x&handles.F.pres.data(k,:)<=max_y&handles.F.pres.data(k,:)>=min_y);
                            hold on
                            
                            %plot(handles.F.doxy_adjusted.data(k,isub),handles.F.pres.data(k,isub),[colormarker{str2num(new_flag)} '+'])
                            plot(handles.F.doxy_adjusted.data(k,isub),handles.F.pres.data(k,isub), '+','Color',colormarker{str2num(new_flag)})
                            % handles.F.psal_qc_new=handles.F.psal_qc;
                            handles.F.doxy_adjusted_qc.data(k,isub)=str2num(new_flag);
                            
                            
                            %         elseif strcmp(parameters{choice},'Temperature(Adjusted)')
                            %             isub=find(handles.F.temp_adjusted.data(k,:)<=max_x&handles.F.temp_adjusted.data(k,:)>=min_x&handles.F.pres_adjusted.data(k,:)<=max_y&handles.F.pres_adjusted.data(k,:)>=min_y);
                            %             hold on
                            %             %keyboard
                            %             plot(handles.F.temp_adjusted.data(k,isub),handles.F.pres_adjusted.data(k,isub),[colormarker{str2num(new_flag)} '+'])
                            %             %handles.F.temp_qc_new=handles.F.temp_qc;
                            %             handles.F.temp_adjusted_qc.data(k,isub)=str2num(new_flag);
                            %         elseif strcmp(parameters{choice},'Salinity(Adjusted)')
                            %             isub=find(handles.F.psal.data(k,:)<=max_x&handles.F.psal.data(k,:)>=min_x&handles.F.pres.data(k,:)<=max_y&handles.F.pres.data(k,:)>=min_y);
                            %             hold on
                            %
                            %             plot(handles.F.psal_adjusted.data(k,isub),handles.F.pres_adjusted.data(k,isub),[colormarker{str2num(new_flag)} '+'])
                            %             % handles.F.psal_qc_new=handles.F.psal_qc;
                            %             handles.F.psal_adjusted_qc.data(k,isub)=str2num(new_flag);
                        end
                    end
                end
            end
            set(handles.selection,'Value',1);
            if handles.KEEPZOOM==0
                set(handles.ax2,'XLimMode','auto')
                set(handles.ax2,'YLimMode','auto')
            end
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: validate
        function validate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to validate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % save the file
            % k=str2double(get(handles.profile,'String'));
            % if k<1
            %     k=1;
            % end
            % if k>length(handles.file_list)
            %     k=length(handles.file_list);
            % end
            
            
            thedate=datestr(now,'yyyymmddHHMMSS');
            parameters=get(handles.parameter,'String');
            choice=get(handles.parameter,'Value');
            param='';
            
            
            if strcmp(parameters{choice},'Temperature')
                param='temp';
                thepres='pres';
            end
            if strcmp(parameters{choice},'Temperature (CNDC)')
                param='temp_cndc';
                thepres='pres';
            end
            if strcmp(parameters{choice},'Salinity')
                param='psal'; thepres='pres';
            end
            if strcmp(parameters{choice},'Oxygene')
                param='doxy'; thepres='pres';
            end
            % if strcmp(parameters{choice},'Temperature(Adjusted)')
            %     param='temp_adjusted'; thepres='pres_adjusted';
            % end
            if strcmp(parameters{choice},'Oxygene(Adjusted)')
                param='doxy_adjusted';thepres='pres';
            end
            if isempty(param)
                helpdlg(['no validation possible for ' parameters{choice}])
                return
            end
            vert_sampl=get(handles.vertical_sampling,'String');
            choice_vert=get(handles.vertical_sampling,'Value');
            %keyboard
            % vertical sampling
            if ~isempty(strfind(vert_sampl{choice_vert},'Primary Sampling'))
                vertical_sampling_scheme='Primary sampling';
            end
            if ~isempty(strfind(vert_sampl{choice_vert},'Near Surface Sampling'))
                vertical_sampling_scheme='Near-surface sampling';
            end
            
            
            param_qc=[param '_qc'];
            for k=1:length(handles.thelist)
                
                [F,DimF,Glo]= read_netcdf_allthefile([handles.DIR_FTP handles.dacname '/' handles.floatname '/profiles/' handles.thelist{k}]);
                
                [G,DimG]=extract_profile_dim(handles.F,handles.Dim,'N_PROF',k);
                
                % verifie qu'on est bien sur le meme cycle
                if F.cycle_number.data~=G.cycle_number.data|F.direction.data~=G.direction.data
                    error('pas le meme cycle')
                end
                
                is_primary=findstr_tab(F.vertical_sampling_scheme.data,vertical_sampling_scheme);
                
                if sum(is_primary)~=1
                    error('pas le vertical sampling scheme requis')
                end
                n_prof=find(is_primary==1);
                %keyboard
                sizF=size(F.pres.data(n_prof,:),2);
                G=format_flags_num2char(G);
                G=replace_nan_byfill(G);
                
                old_qc = F.(param_qc).data(n_prof,:);
                new_qc = G.(param_qc).data(1:sizF);
                
                
                %trouve les start_pres,stop_pres et  prev_val
                isneq=find(old_qc~=new_qc);
                if ~isempty(isneq)
                    clear start_pres stop_pres prev_val
                    start_pres(1)=F.(thepres).data(n_prof,isneq(1));
                    stop_pres(1)=F.(thepres).data(n_prof,isneq(end));
                    all_qc=str2num(old_qc(isneq)');
                    prev_val(1)=all_qc(1);
                    
                    kk=1;
                    for jk=2:length(isneq)
                        
                        if (isneq(jk)~=isneq(jk-1)+1)| all_qc(jk)~=all_qc(jk-1)
                            kk=kk+1;
                            stop_pres(kk-1) = F.(thepres).data(n_prof,isneq(jk-1));
                            start_pres(kk) = F.(thepres).data(n_prof,isneq(jk));
                            stop_pres(kk) =F.(thepres).data(n_prof,isneq(end));
                            prev_val(kk) =all_qc(jk);
                        end
                    end
                    
                    % ajout cc 09/11/2021 : possibilite de flagguer
                    % DOXY_ADJUSTED
                    F.(param_qc).data(n_prof,:)=G.(param_qc).data(1:sizF);
                    if strcmp(parameters{choice},'Oxygene(Adjusted)')
                        index_QC4_DOXY = strfind(F.doxy_adjusted_qc.data(n_prof,:),'4');
                        F.doxy_adjusted.data(n_prof,index_QC4_DOXY) = F.doxy_adjusted.FillValue_ ;
                        F.doxy_adjusted_error.data(n_prof,index_QC4_DOXY) = F.doxy_adjusted_error.FillValue_ ;
                    end
                    % PROFILE_QC
                    %keyboard
                    [F,GlobQC,ValnumQC]=check_profile_qc(F);
                    
                    for jk=1:length(start_pres)
                        
                        % HISTORY fields
                        new_hist = DimF.n_history.dimlength+1;
                        
                        allfields = fieldnames(F);
                        
                        ii = strfind(allfields,'history_');
                        is_history = find(~cellfun('isempty',ii));
                        
                        F = check_FirstDimArray_is(F,'N_HISTORY');
                        
                        if DimF.n_history.dimlength~=0
                            % remplit un historique de plus avec des FillValue
                            [F_ex,DimF_ex]=extract_profile_dim(F,DimF,'N_HISTORY',1);
                            %keyboard
                            for ik = is_history'
                                oneChamp =allfields{ik};
                                ii=F_ex.(oneChamp).data~=F_ex.(oneChamp).FillValue_;
                                F_ex.(oneChamp).data(ii)=F_ex.(oneChamp).FillValue_;
                            end
                            
                            [F,DimF] = cat_profile_dim(F,F_ex,DimF,DimF_ex,'N_HISTORY');
                        else
                            for ik = is_history'
                                oneChamp =allfields{ik};
                                siz(1)=1;
                                for tk=2:length(F.(oneChamp).dim)
                                    siz(tk) = DimF.(lower(F.(oneChamp).dim{tk})).dimlength;
                                end
                                F.(oneChamp).data = repmat(F_ex.(oneChamp).FillValue_,siz);
                                DimF.n_history.dimlength=1;
                            end
                        end
                        
                        
                        % remplit l'historique par la valeur appropriee
                        step='ARSQ';
                        l_in=length(step);
                        F.history_step.data(new_hist,n_prof,1:l_in)=step;
                        
                        institution='IF';
                        l_in=length(institution);
                        F.history_institution.data(new_hist,n_prof,1:l_in)=institution;
                        
                        soft='SCOO';
                        l_so=length(soft);
                        F.history_software.data(new_hist,n_prof,1:l_so)=soft;
                        
                        soft_release='1.4';
                        l_so_r=length(soft_release);
                        F.history_software_release.data(new_hist,n_prof,1:l_so_r)=soft_release;
                        
                        F.history_date.data(new_hist,n_prof,:)=thedate;
                        
                        action='CF';
                        l_ac=length(action);
                        F.history_action.data(new_hist,n_prof,1:l_ac)=action;
                        
                        parameter=upper(param);
                        l_pa=length(parameter);
                        F.history_parameter.data(new_hist,n_prof,1:l_pa)=parameter;
                        
                        
                        F.history_start_pres.data(new_hist,n_prof)=start_pres(jk);
                        F.history_stop_pres.data(new_hist,n_prof)=stop_pres(jk);
                        
                        
                        F.history_previous_value.data(new_hist,n_prof)=prev_val(jk);
                        F.date_update.data = thedate';
                        
                    end
                    F = check_FirstDimArray_is(F,'N_PROF');
                    ficout=[handles.DIR_FTP handles.dacname '/' handles.floatname '/profiles/' handles.thelist{k}];
                    
                    
                    %ficout=['/tmp/' handles.file_list{k}];
                    create_netcdf_allthefile(F,DimF,ficout,Glo);
                end
            end
        end

        % Value changed function: vertical_sampling
        function vertical_sampling_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to vertical_sampling (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns vertical_sampling contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from vertical_sampling
            profile_Callback(app,event)
        end

        % Button pushed function: ExitButton
        function exit_callback(app, event)
            
            close all
            delete(app)
        end

        % Value changed function: Addsigma0CheckBox
        function Addsigma0CheckBoxValueChanged(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            
            choice = app.parameter.Value;
            if strcmp(choice,'Sigma0')|strcmp(choice,'ThetaS')|strcmp(choice,'Oxygene')|strcmp(choice,'Sigma0(Adjusted)')|strcmp(choice,'ThetaS(Adjusted)')|strcmp(choice,'Oxygene(Adjusted)')
                app.Addsigma0CheckBox.Value=0;
            end
            value = app.Addsigma0CheckBox.Value;
            if value==1&isfield(handles,'F')&isfield(handles.F,'psal')
               cla(handles.ax2b)
               if handles.KEEPZOOM==0
               handles.ax2b.YLimMode='auto';
               handles.ax2.YLimMode='auto';
               end
               ptemp=sw_ptmp(handles.F.psal.data,handles.F.temp.data,handles.F.pres.data,0);
               handles.F.sigma0=handles.F.temp;% init
               handles.F.sigma0_qc=handles.F.temp_qc; % init
               handles.F.sigma0.data=sw_dens0(handles.F.psal.data,ptemp)-1000;
               %handles.F.sigma0_qc.data=handles.F.psal_qc.data;
               axes(handles.ax2b)
               n_prof=size(handles.F.temp.data,1);
               thetitle= plot_profile_with_flag_v2(handles.F,'sigma0','pres',[1:n_prof],'LineWidth',0.5,'Marker','grey','LineColor',[0.5 0.5 0.5]);
               handles.ax2b.XAxisLocation = 'top';
               handles.ax2b.YAxisLocation = 'right';
               handles.ax2b.Box = 'off';
               handles.ax2.Box = 'off';
               set(handles.ax2b,'Ydir','reverse')
               %handles.ax2b.XLabel.Position=[handles.ax2b.XLabel.Position(1) handles.ax2b.XLabel.Position(2)+((handles.ax2b.YLim(2)-handles.ax2b.YLim(1))/40) handles.ax2b.XLabel.Position(3)];
               handles.ax2b.XLabel.String=['SIGMA 0'];
               linkaxes([ handles.ax2  handles.ax2b],'y');
               grid off
               handles.ax2b.XColor=[0.5 0.5 0.5];
               handles.ax2b.YColor=[0.5 0.5 0.5];
               axes(handles.ax2)
               handles.ax2.Color = 'none';
               handles.ax2b.Visible='on';
            end
            if value==0
               cla(handles.ax2b)
               linkaxes([ handles.ax2  handles.ax2b],'off');
               if handles.KEEPZOOM==0
               handles.ax2b.YLimMode='auto';
               handles.ax2.YLimMode='auto';
               end
               handles.ax2b.Visible='off';
               handles.ax2.Color = 'w';
               handles.ax2.Box = 'on';
            end
            
            
        end

        % Value changed function: AddtempCheckBox
        function AddTempCheckBoxValueChanged(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            choice = app.parameter.Value;
            if ~strcmp(choice,'Temperature (CNDC)')
                app.AddtempCheckBox.Value=0;
            end
            value = app.AddtempCheckBox.Value;
            if value==1&isfield(handles,'F')
                cla(handles.ax2b)
                if handles.KEEPZOOM==0
                    handles.ax2b.YLimMode='auto';
                    handles.ax2.YLimMode='auto';
                    handles.ax2b.XLimMode='auto';
                    handles.ax2.XLimMode='auto';
                end
                
                axes(handles.ax2b)
                n_prof=size(handles.F.temp.data,1);
                thetitle= plot_profile_with_flag_v2(handles.F,'temp','pres',[1:n_prof],'LineWidth',0.5,'Marker','grey','LineColor',[0.5 0.5 0.5]);
                handles.ax2b.XAxisLocation = 'top';
                handles.ax2b.YAxisLocation = 'right';
                handles.ax2b.Box = 'off';
                handles.ax2.Box = 'off';
                set(handles.ax2b,'Ydir','reverse')
                %handles.ax2b.XLabel.Position=[handles.ax2b.XLabel.Position(1) handles.ax2b.XLabel.Position(2)+((handles.ax2b.YLim(2)-handles.ax2b.YLim(1))/40) handles.ax2b.XLabel.Position(3)];
                handles.ax2b.XLabel.String=['TEMP'];
                linkaxes([ handles.ax2  handles.ax2b],'xy');

                grid off
                handles.ax2b.XColor=[0.5 0.5 0.5];
                handles.ax2b.YColor=[0.5 0.5 0.5];
                axes(handles.ax2)
                handles.ax2.Color = 'none';
                handles.ax2b.Visible='on';
            end
            if value==0
                cla(handles.ax2b)
                linkaxes([ handles.ax2  handles.ax2b],'off');
                if handles.KEEPZOOM==0
                    handles.ax2b.YLimMode='auto';
                    handles.ax2.YLimMode='auto';
                    handles.ax2b.XLimMode='auto';
                    handles.ax2.XLimMode='auto';
                end
                handles.ax2b.Visible='off';
                handles.ax2.Color = 'w';
                handles.ax2.Box = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'flag_diag';
            app.figure1.Tag = 'figure1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Enter NEW flag';
            app.new_flag = uieditfield(app.figure1, 'text');
            app.new_flag.ValueChangedFcn = createCallbackFcn(app, @new_flag_Callback, true);
            app.new_flag.Tag = 'new_flag';
            app.selection = uidropdown(app.figure1);
            app.selection.Items = {'', 'Rectangle', 'Single Point'};
            app.selection.ValueChangedFcn = createCallbackFcn(app, @selection_Callback, true);
            app.selection.Tag = 'selection';
            app.selection.Value = '';
            app.parameter = uidropdown(app.figure1);
            app.parameter.Items = {'-', 'Temperature', 'Temperature (CNDC)', 'Salinity', 'Ptemp', 'Sigma0', 'ThetaS', 'Oxygene', 'Temperature(Adjusted)', 'Salinity(Adjusted)', 'Ptemp(Adjusted)', 'Sigma0(adjusted)', 'ThetaS(Adjusted)', 'Oxygene(Adjusted)'};
            app.parameter.ValueChangedFcn = createCallbackFcn(app, @parameter_Callback, true);
            app.parameter.Tag = 'parameter';
            app.parameter.Value = '-';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'PARAMETER';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'PROFILES';
            app.profile = uieditfield(app.figure1, 'text');
            app.profile.ValueChangedFcn = createCallbackFcn(app, @profile_Callback, true);
            app.profile.Tag = 'profile';
            app.previous = uibutton(app.figure1, 'push');
            app.previous.ButtonPushedFcn = createCallbackFcn(app, @previous_Callback, true);
            app.previous.Tag = 'previous';
            app.previous.Text = '<< PREVIOUS';
            app.NEXT = uibutton(app.figure1, 'push');
            app.NEXT.ButtonPushedFcn = createCallbackFcn(app, @NEXT_Callback, true);
            app.NEXT.Tag = 'NEXT';
            app.NEXT.Text = 'NEXT>>';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'FLAG MODIFICATION';
            app.validate = uibutton(app.figure1, 'push');
            app.validate.ButtonPushedFcn = createCallbackFcn(app, @validate_Callback, true);
            app.validate.Tag = 'validate';
            app.validate.Text = 'VALIDATE';
            app.cancel = uibutton(app.figure1, 'push');
            app.cancel.ButtonPushedFcn = createCallbackFcn(app, @cancel_Callback, true);
            app.cancel.Tag = 'cancel';
            app.cancel.Text = 'CANCEL';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'SELECTION TOOL';
            app.text = uilabel(app.figure1);
            app.text.Tag = 'text';
            app.text.Text = 'VERTICAL SAMPLING';
            app.vertical_sampling = uidropdown(app.figure1);
            app.vertical_sampling.Items = {'Primary Sampling', 'Near Surface Sampling'};
            app.vertical_sampling.ValueChangedFcn = createCallbackFcn(app, @vertical_sampling_Callback, true);
            app.vertical_sampling.Tag = 'vertical_sampling';
            app.vertical_sampling.Value = 'Primary Sampling';
            app.pushbutton6 = uibutton(app.figure1, 'push');
            app.pushbutton6.ButtonPushedFcn = createCallbackFcn(app, @printfig_Callback, true);
            app.pushbutton6.Tag = 'pushbutton6';
            app.pushbutton6.Text = 'PRINT';
            app.ExitButton = uibutton(app.figure1, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @exit_callback, true);
            app.ExitButton.Text = 'Exit';
            app.Addsigma0CheckBox = uicheckbox(app.figure1);
            app.Addsigma0CheckBox.ValueChangedFcn = createCallbackFcn(app, @Addsigma0CheckBoxValueChanged, true);
            app.Addsigma0CheckBox.Text = 'Add sigma0';
            app.AddtempCheckBox = uicheckbox(app.figure1);
            app.AddtempCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddTempCheckBoxValueChanged, true);
            app.AddtempCheckBox.Text = 'Add temp';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = flag_diag_App_v3(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)flag_diag_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end